classdef ea3hw1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SlidingBoxTab                   matlab.ui.container.Tab
        GraphsPanel                     matlab.ui.container.Panel
        energy_graph                    matlab.ui.control.UIAxes
        speed_graph                     matlab.ui.control.UIAxes
        position_graph                  matlab.ui.control.UIAxes
        InputsPanel                     matlab.ui.container.Panel
        stop_value                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        stop_type                       matlab.ui.control.DropDown
        StopsimulationwhenDropDownLabel  matlab.ui.control.Label
        theta                           matlab.ui.control.NumericEditField
        RampangledegreesEditFieldLabel  matlab.ui.control.Label
        m                               matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        u                               matlab.ui.control.NumericEditField
        CoefficientoffrictionEditFieldLabel  matlab.ui.control.Label
        xaxis                           matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        VisualizationPanel              matlab.ui.container.Panel
        visualization                   matlab.ui.control.UIAxes
        InitialandFinalConditionsPanel  matlab.ui.container.Panel
        FinalEEditField                 matlab.ui.control.EditField
        FinalEEditFieldLabel            matlab.ui.control.Label
        InitialEEditField               matlab.ui.control.EditField
        InitialEEditFieldLabel_2        matlab.ui.control.Label
        FinalthermalEditField           matlab.ui.control.EditField
        FinalthermalEditFieldLabel      matlab.ui.control.Label
        FinalPEEditField                matlab.ui.control.EditField
        FinalPEEditFieldLabel           matlab.ui.control.Label
        FinalKEEditField                matlab.ui.control.EditField
        InitialXEditField_2Label_3      matlab.ui.control.Label
        FinalvEditField                 matlab.ui.control.EditField
        FinalvEditFieldLabel            matlab.ui.control.Label
        FinalxEditField                 matlab.ui.control.EditField
        FinalxEditFieldLabel            matlab.ui.control.Label
        FinaltEditField                 matlab.ui.control.EditField
        FinaltEditFieldLabel            matlab.ui.control.Label
        InitialthermalEditField         matlab.ui.control.EditField
        InitialthermalEditFieldLabel    matlab.ui.control.Label
        InitialPEEditField              matlab.ui.control.EditField
        InitialPEEditFieldLabel         matlab.ui.control.Label
        InitialKEEditField              matlab.ui.control.EditField
        InitialXEditField_2Label_2      matlab.ui.control.Label
        InitialvEditField               matlab.ui.control.EditField
        InitialvEditFieldLabel          matlab.ui.control.Label
        InitialxEditField               matlab.ui.control.EditField
        InitialxEditFieldLabel          matlab.ui.control.Label
        InitialtEditField               matlab.ui.control.EditField
        InitialtEditFieldLabel          matlab.ui.control.Label
        CollisionTab_2                  matlab.ui.container.Tab
        GraphsPanel_2                   matlab.ui.container.Panel
        energy_graph_2                  matlab.ui.control.UIAxes
        speed_graph_2                   matlab.ui.control.UIAxes
        position_graph_2                matlab.ui.control.UIAxes
        InputsPanel_2                   matlab.ui.container.Panel
        stop_value_2                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        stop_type_2                     matlab.ui.control.DropDown
        StopsimulationwhenDropDownLabel_2  matlab.ui.control.Label
        theta_2                         matlab.ui.control.NumericEditField
        RampangledegreesEditFieldLabel_2  matlab.ui.control.Label
        m_2                             matlab.ui.control.NumericEditField
        MasskgEditFieldLabel_2          matlab.ui.control.Label
        u_2                             matlab.ui.control.NumericEditField
        CoefficientoffrictionEditFieldLabel_2  matlab.ui.control.Label
        xaxis_2                         matlab.ui.control.DropDown
        xaxisDropDownLabel_2            matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        VisualizationPanel_2            matlab.ui.container.Panel
        visualization_2                 matlab.ui.control.UIAxes
        InitialandFinalConditionsPanel_2  matlab.ui.container.Panel
        FinalEEditField_2               matlab.ui.control.EditField
        FinalEEditField_2Label          matlab.ui.control.Label
        InitialEEditField_2             matlab.ui.control.EditField
        InitialEEditField_2Label        matlab.ui.control.Label
        FinalthermalEditField_2         matlab.ui.control.EditField
        FinalthermalEditField_2Label    matlab.ui.control.Label
        FinalPEEditField_2              matlab.ui.control.EditField
        FinalPEEditField_2Label         matlab.ui.control.Label
        FinalKEEditField_2              matlab.ui.control.EditField
        FinalKEEditField_2Label         matlab.ui.control.Label
        FinalvEditField_2               matlab.ui.control.EditField
        FinalvEditField_2Label          matlab.ui.control.Label
        FinalxEditField_2               matlab.ui.control.EditField
        FinalxEditField_2Label          matlab.ui.control.Label
        FinaltEditField_2               matlab.ui.control.EditField
        FinaltEditField_2Label          matlab.ui.control.Label
        InitialthermalEditField_2       matlab.ui.control.EditField
        InitialthermalEditField_2Label  matlab.ui.control.Label
        InitialPEEditField_2            matlab.ui.control.EditField
        InitialPEEditField_2Label       matlab.ui.control.Label
        InitialKEEditField_2            matlab.ui.control.EditField
        InitialKEEditField_2Label       matlab.ui.control.Label
        InitialvEditField_2             matlab.ui.control.EditField
        InitialvEditField_2Label        matlab.ui.control.Label
        InitialxEditField_2             matlab.ui.control.EditField
        InitialxEditField_2Label        matlab.ui.control.Label
        InitialtEditField_2             matlab.ui.control.EditField
        InitialtEditField_2Label        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button down function: UIFigure
        function RunSlide(app, event)


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Read inputs from GUI
            m = app.m.Value;
            mu = app.u.Value;
            theta = app.theta.Value;

            stopValue = app.stop_value.Value;

            g = 9.81;

            v0 = 0;
            x0 = 0;

            % solve acceleration
            a = g * (sind(theta) - mu*cosd(theta));

            x1 = stopValue;

            if(a <= 0)
                uialert(app.UIFigure, ...
                'The box will not accelerate down the ramp with these inputs.', ...
                'Simulation Error');
                return
            end

            tFinal = sqrt(2*x1/a);

            t = linspace(0,tFinal,200);
            
            % Calculate motion
            x = x0 + v0*t + 0.5*a*t.^2;
            v = v0 + a*t;
            
            % Calculate energy
            KE = 0.5*m*v.^2;


            h0 = x1*sind(theta);
            h1 = h0 - x*sind(theta);

            PE = m*g*h1;

            % Friction
            frictionForce = mu*m*g*cosd(theta);
            thermalEnergy = frictionForce*x;
            totalEnergy = KE + PE + thermalEnergy;

            E = KE+PE + thermalEnergy;

            plot(app.position_graph,t,x,'LineWidth',2)
            title(app.position_graph,'Position vs Time')
            xlabel(app.position_graph,'Time (s)')
            ylabel(app.position_graph,'Position (m)')
            grid(app.position_graph,'on')

            plot(app.speed_graph,t,v,'LineWidth',2)
            title(app.speed_graph,'Speed vs Time')
            xlabel(app.speed_graph,'Time (s)')
            ylabel(app.speed_graph,'Speed (m/s)')
            grid(app.speed_graph,'on')

            plot(app.energy_graph,t,KE,'LineWidth',2)
            hold(app.energy_graph,'on')
            
            plot(app.energy_graph,t,PE,'LineWidth',2)
            plot(app.energy_graph,t,thermalEnergy,'LineWidth',2)
            plot(app.energy_graph,t,totalEnergy,'LineWidth',2)
            
            hold(app.energy_graph,'off')
            
            legend(app.energy_graph, ...
                'Kinetic', ...
                'Potential', ...
                'Thermal', ...
                'Total')

            %% VISUALIZATION

            ax = app.visualization;
            
            % clear previous animation
            cla(ax)
            
            % ramp length
            rampLength = max(x);
            
            % ramp geometry
            thetaRad = deg2rad(theta);
            
            rampX = [0 rampLength*cos(thetaRad)];
            rampY = [rampLength*sin(thetaRad) 0];
            
            % draw ramp
            plot(ax, rampX, rampY, 'LineWidth', 4)
            hold(ax, 'on')
            
            % set axis limits
            axis(ax, 'equal')
            
            xlim(ax, [-1 rampLength*cos(thetaRad)+1])
            ylim(ax, [-1 rampLength*sin(thetaRad)+2])
            
            % hide axes if you want it to look more like an animation
            ax.XTick = [];
            ax.YTick = [];
            
            title(ax, 'Visualization')
            
            %% create box
            
            boxSize = 0.5;
            
            % starting point of box
            boxX = rampX(1);
            boxY = rampY(1);
            
            % Normalize mass
            massMin = 1;
            massMax = 20;
            
            massScale = (m - massMin) / (massMax - massMin);
            massScale = max(0, min(1, massScale));
            
            % Match your app's pink theme
            lightPink = [1.00 0.72 0.91];
            darkPink  = [0.55 0.16 0.43];
            
            % Mass-based box color
            boxColor = lightPink + massScale * (darkPink - lightPink);
            
            boxSize = 0.5;

            % Define box corners before rotation
            corners = [...
                -boxSize/2, 0;
                 boxSize/2, 0;
                 boxSize/2, boxSize;
                -boxSize/2, boxSize];
            
            % Rotate box to match ramp angle
            R = [cos(-thetaRad), -sin(-thetaRad);
                 sin(-thetaRad),  cos(-thetaRad)];
            
            rotatedCorners = (R * corners')';

            box = patch(app.visualization, ...
                rotatedCorners(:,1), ...
                rotatedCorners(:,2), ...
                boxColor);
            
            %% animate
            
            for i = 1:length(t)

                s = x(i);
            
                boxX = s*cos(thetaRad);
                boxY = (rampLength - s)*sin(thetaRad);
            
                newCorners = rotatedCorners;
            
                newCorners(:,1) = newCorners(:,1) + boxX;
                newCorners(:,2) = newCorners(:,2) + boxY;
            
                box.XData = newCorners(:,1);
                box.YData = newCorners(:,2);
            
                drawnow
                pause(t(2)-t(1))
            
            end
            
            hold(ax, 'off')

            %% INITIAL AND FINAL CONDITIONS WITH UNITS

            % Time
            app.InitialtEditField.Value = sprintf('%.2f s', t(1));
            app.FinaltEditField.Value   = sprintf('%.2f s', t(end));
            
            % Position
            app.InitialxEditField.Value = sprintf('%.2f m', x(1));
            app.FinalxEditField.Value   = sprintf('%.2f m', x(end));
            
            % Velocity
            app.InitialvEditField.Value = sprintf('%.2f m/s', v(1));
            app.FinalvEditField.Value   = sprintf('%.2f m/s', v(end));
            
            % Kinetic Energy
            app.InitialKEEditField.Value = sprintf('%.2f J', KE(1));
            app.FinalKEEditField.Value   = sprintf('%.2f J', KE(end));
            
            % Potential Energy
            app.InitialPEEditField.Value = sprintf('%.2f J', PE(1));
            app.FinalPEEditField.Value   = sprintf('%.2f J', PE(end));
            
            % Thermal Energy
            app.InitialthermalEditField.Value = sprintf('%.2f J', thermalEnergy(1));
            app.FinalthermalEditField.Value   = sprintf('%.2f J', thermalEnergy(end));
            
            % Total Energy
            app.InitialEEditField.Value = sprintf('%.2f J', totalEnergy(1));
            app.FinalEEditField.Value   = sprintf('%.2f J', totalEnergy(end));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @RunSlide, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SlidingBoxTab = uitab(app.TabGroup);
            app.SlidingBoxTab.Title = 'Sliding Box';
            app.InitialandFinalConditionsPanel = uipanel(app.SlidingBoxTab);
            app.InitialandFinalConditionsPanel.Title = 'Initial and Final Conditions';
            app.InitialtEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialtEditFieldLabel.Text = 'Initial t';
            app.InitialtEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialxEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialxEditFieldLabel.Text = 'Initial x';
            app.InitialxEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialvEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialvEditFieldLabel.Text = 'Initial v';
            app.InitialvEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialXEditField_2Label_2 = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialXEditField_2Label_2.Text = 'Initial KE';
            app.InitialKEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialPEEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialPEEditFieldLabel.Text = 'Initial PE';
            app.InitialPEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialthermalEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialthermalEditFieldLabel.Text = 'Initial thermal';
            app.InitialthermalEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinaltEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinaltEditFieldLabel.Text = 'Final t';
            app.FinaltEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinalxEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinalxEditFieldLabel.Text = 'Final x';
            app.FinalxEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinalvEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinalvEditFieldLabel.Text = 'Final v';
            app.FinalvEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialXEditField_2Label_3 = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialXEditField_2Label_3.Text = 'Final KE';
            app.FinalKEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinalPEEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinalPEEditFieldLabel.Text = 'Final PE';
            app.FinalPEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinalthermalEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinalthermalEditFieldLabel.Text = 'Final thermal';
            app.FinalthermalEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.InitialEEditFieldLabel_2 = uilabel(app.InitialandFinalConditionsPanel);
            app.InitialEEditFieldLabel_2.Text = 'Initial E';
            app.InitialEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.FinalEEditFieldLabel = uilabel(app.InitialandFinalConditionsPanel);
            app.FinalEEditFieldLabel.Text = 'Final E';
            app.FinalEEditField = uieditfield(app.InitialandFinalConditionsPanel, 'text');
            app.VisualizationPanel = uipanel(app.SlidingBoxTab);
            app.VisualizationPanel.Title = 'Visualization';
            app.visualization = uiaxes(app.VisualizationPanel);
            app.visualization.XTick = [];
            app.visualization.YTick = [];
            app.InputsPanel = uipanel(app.SlidingBoxTab);
            app.InputsPanel.Title = 'Inputs';
            app.RunButton = uibutton(app.InputsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Simulation';
            app.xaxisDropDownLabel = uilabel(app.InputsPanel);
            app.xaxisDropDownLabel.Text = 'x-axis ';
            app.xaxis = uidropdown(app.InputsPanel);
            app.xaxis.Items = {'along the ground ', 'along the ramp', ''};
            app.xaxis.Value = 'along the ground ';
            app.CoefficientoffrictionEditFieldLabel = uilabel(app.InputsPanel);
            app.CoefficientoffrictionEditFieldLabel.Text = 'Coefficient of friction';
            app.u = uieditfield(app.InputsPanel, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.InputsPanel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.m = uieditfield(app.InputsPanel, 'numeric');
            app.RampangledegreesEditFieldLabel = uilabel(app.InputsPanel);
            app.RampangledegreesEditFieldLabel.Text = 'Ramp angle (degrees)';
            app.theta = uieditfield(app.InputsPanel, 'numeric');
            app.StopsimulationwhenDropDownLabel = uilabel(app.InputsPanel);
            app.StopsimulationwhenDropDownLabel.Text = 'Stop simulation when';
            app.stop_type = uidropdown(app.InputsPanel);
            app.stop_type.Items = {'position', 'velocity'};
            app.stop_type.Value = 'position';
            app.Label = uilabel(app.InputsPanel);
            app.Label.Text = '=';
            app.stop_value = uieditfield(app.InputsPanel, 'numeric');
            app.GraphsPanel = uipanel(app.SlidingBoxTab);
            app.GraphsPanel.Title = 'Graphs';
            app.position_graph = uiaxes(app.GraphsPanel);
            title(app.position_graph, 'Position vs Time ')
            xlabel(app.position_graph, 'Time')
            ylabel(app.position_graph, 'Position')
            zlabel(app.position_graph, 'Z')
            app.speed_graph = uiaxes(app.GraphsPanel);
            title(app.speed_graph, 'Speed vs Time ')
            xlabel(app.speed_graph, 'X')
            ylabel(app.speed_graph, 'Speed')
            zlabel(app.speed_graph, 'Z')
            app.energy_graph = uiaxes(app.GraphsPanel);
            title(app.energy_graph, 'Energy vs Time ')
            xlabel(app.energy_graph, 'Time')
            ylabel(app.energy_graph, 'Energy')
            zlabel(app.energy_graph, 'Z')
            app.CollisionTab_2 = uitab(app.TabGroup);
            app.CollisionTab_2.Title = 'Collision';
            app.InitialandFinalConditionsPanel_2 = uipanel(app.CollisionTab_2);
            app.InitialandFinalConditionsPanel_2.Title = 'Initial and Final Conditions';
            app.InitialtEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialtEditField_2Label.Text = 'Initial t';
            app.InitialtEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialxEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialxEditField_2Label.Text = 'Initial x';
            app.InitialxEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialvEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialvEditField_2Label.Text = 'Initial v';
            app.InitialvEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialKEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialKEEditField_2Label.Text = 'Initial KE';
            app.InitialKEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialPEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialPEEditField_2Label.Text = 'Initial PE';
            app.InitialPEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialthermalEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialthermalEditField_2Label.Text = 'Initial thermal';
            app.InitialthermalEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinaltEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinaltEditField_2Label.Text = 'Final t';
            app.FinaltEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalxEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalxEditField_2Label.Text = 'Final x';
            app.FinalxEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalvEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalvEditField_2Label.Text = 'Final v';
            app.FinalvEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalKEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalKEEditField_2Label.Text = 'Final KE';
            app.FinalKEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalPEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalPEEditField_2Label.Text = 'Final PE';
            app.FinalPEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalthermalEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalthermalEditField_2Label.Text = 'Final thermal';
            app.FinalthermalEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.InitialEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.InitialEEditField_2Label.Text = 'Initial E';
            app.InitialEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.FinalEEditField_2Label = uilabel(app.InitialandFinalConditionsPanel_2);
            app.FinalEEditField_2Label.Text = 'Final E';
            app.FinalEEditField_2 = uieditfield(app.InitialandFinalConditionsPanel_2, 'text');
            app.VisualizationPanel_2 = uipanel(app.CollisionTab_2);
            app.VisualizationPanel_2.Title = 'Visualization';
            app.visualization_2 = uiaxes(app.VisualizationPanel_2);
            app.visualization_2.XTick = [];
            app.visualization_2.YTick = [];
            app.InputsPanel_2 = uipanel(app.CollisionTab_2);
            app.InputsPanel_2.Title = 'Inputs';
            app.RunButton_2 = uibutton(app.InputsPanel_2, 'push');
            app.RunButton_2.Text = 'Run Simulation';
            app.xaxisDropDownLabel_2 = uilabel(app.InputsPanel_2);
            app.xaxisDropDownLabel_2.Text = 'x-axis ';
            app.xaxis_2 = uidropdown(app.InputsPanel_2);
            app.xaxis_2.Items = {'along the ground ', 'along the ramp', ''};
            app.xaxis_2.Value = 'along the ground ';
            app.CoefficientoffrictionEditFieldLabel_2 = uilabel(app.InputsPanel_2);
            app.CoefficientoffrictionEditFieldLabel_2.Text = 'Coefficient of friction';
            app.u_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.MasskgEditFieldLabel_2 = uilabel(app.InputsPanel_2);
            app.MasskgEditFieldLabel_2.Text = 'Mass (kg)';
            app.m_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.RampangledegreesEditFieldLabel_2 = uilabel(app.InputsPanel_2);
            app.RampangledegreesEditFieldLabel_2.Text = 'Ramp angle (degrees)';
            app.theta_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.StopsimulationwhenDropDownLabel_2 = uilabel(app.InputsPanel_2);
            app.StopsimulationwhenDropDownLabel_2.Text = 'Stop simulation when';
            app.stop_type_2 = uidropdown(app.InputsPanel_2);
            app.stop_type_2.Items = {'position', 'velocity'};
            app.stop_type_2.Value = 'position';
            app.Label_2 = uilabel(app.InputsPanel_2);
            app.Label_2.Text = '=';
            app.stop_value_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.GraphsPanel_2 = uipanel(app.CollisionTab_2);
            app.GraphsPanel_2.Title = 'Graphs';
            app.position_graph_2 = uiaxes(app.GraphsPanel_2);
            title(app.position_graph_2, 'Position vs Time ')
            xlabel(app.position_graph_2, 'Time')
            ylabel(app.position_graph_2, 'Position')
            zlabel(app.position_graph_2, 'Z')
            app.speed_graph_2 = uiaxes(app.GraphsPanel_2);
            title(app.speed_graph_2, 'Speed vs Time ')
            xlabel(app.speed_graph_2, 'X')
            ylabel(app.speed_graph_2, 'Speed')
            zlabel(app.speed_graph_2, 'Z')
            app.energy_graph_2 = uiaxes(app.GraphsPanel_2);
            title(app.energy_graph_2, 'Energy vs Time ')
            xlabel(app.energy_graph_2, 'Time')
            ylabel(app.energy_graph_2, 'Energy')
            zlabel(app.energy_graph_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea3hw1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end